classdef wifiapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        UIAxes                        matlab.ui.control.UIAxes
        ColorDropDownLabel            matlab.ui.control.Label
        ColorDropDown                 matlab.ui.control.DropDown
        SaveimageButton               matlab.ui.control.Button
        WiFiVisualiserLabel           matlab.ui.control.Label
        SurfaceDropDownLabel          matlab.ui.control.Label
        SurfaceDropDown               matlab.ui.control.DropDown
        DrawWiFiButton                matlab.ui.control.Button
        RouterpositionEditFieldLabel  matlab.ui.control.Label
        RouterpositionEditField       matlab.ui.control.EditField
        FrequencyButtonGroup          matlab.ui.container.ButtonGroup
        GHzButton                     matlab.ui.control.RadioButton
        GHzButton_2                   matlab.ui.control.RadioButton
        FilenameEditFieldLabel        matlab.ui.control.Label
        FilenameEditField             matlab.ui.control.EditField
    end


    properties (Access = private)
        planMatrix 
        router 
        crossMark 
        wifiSource 
        activeImage 
        activeColormap 
    end

    methods (Access = private)
    
        function Ei = calculateSignal(app, router)
            scale = 0.01; % 1px = 1cm in reality 
            airCoeff = 1;
            concreteCoeff = 2.12 - 0.031i;
               
            if app.GHzButton.Value 
                waveLength = 0.12;
            else
                waveLength = 0.0576;
            end
            k = 2*pi/waveLength;

            numOfColors = 20;
            u = zeros(size(app.planMatrix));
            u(app.planMatrix == 1) = (k*airCoeff)^2;
            u(app.planMatrix ~= 1) = (k*concreteCoeff)^2;
            
            
            [dimx, dimy] = size(u);
            
            xs = zeros(5*numel(u), 1);
            ys = zeros(5*numel(u), 1);
            vs = zeros(5*numel(u), 1);
            i = 1;
            for y = 1:dimy
                for x = 1:dimx
                    xm = mod(x+dimx-2, dimx) + 1;
                    xp = mod(x, dimx) + 1;
                    ym = mod(y+dimy-2, dimy) + 1;
                    yp = mod(y, dimy) + 1;
                    num = sub2ind(size(u), x, y);
            
                    xs(i) = num;
                    ys(i) = num;
                    vs(i) = u(x, y) - 4*scale^(-2);
                    i = i + 1;
                    
                    Midx = [xp y; xm y; x yp; x ym];
                    for k=1:length(Midx)
                        idx = [Midx(k) Midx(k+length(Midx))];
                        xs(i) = num;
                        ys(i) = sub2ind(size(u), idx(1), idx(2));
            
                        if 1<x && x<dimx && 1<y && y<dimy
                            vs(i) = scale^(-2);
                        else
                            vs(i) = 1e-9;
                        end
                        i = i + 1;
            
                    end
                end
            end
            M = sparse(xs, ys, vs, numel(u), numel(u));
            f = zeros(size(u));
            f(router(1), router(2)) = 2e3;
            
            f = reshape(f, numel(f), 1);
            disp("Calculation has been started...");
            
            A = reshape(M\f, size(u));
            
            E = 20*log10(real(A) .* real(A));
            E(E < -105) = -105;
            minE = min(min(E));
            maxE = max(max(E));
            
            rescale =  round(1 + numOfColors*(E - minE)/(maxE - minE));
            Ei = round(min(numOfColors, max(1, rescale)));
            Ei(app.planMatrix == 0) = 20;
        end
        
        
        function draw(app, X, color, wifi)
            switch color
                case 'green'
                    map = [
                        0.0,0.22535573051301544,0.1012816614038329;
                        0.0,0.2994212432457489,0.13582753998841213;
                        0.0,0.3702526819594031,0.1683580834780099;
                        0.07401166125325728,0.437424329074464,0.1994259856417963;
                        0.14131148585511447,0.5007244016535223,0.22971652431097217;
                        0.20297996636804222,0.5600777103127256,0.25992337833083834;
                        0.26231679981676104,0.6154939036028116,0.2906951187151281;
                        0.3201498750242505,0.6670323753668519,0.32261839759604394;
                        0.37677796165165667,0.7147765766117375,0.35622926358537466;
                        0.4323948649766449,0.7588124000741077,0.3920519214881119;
                        0.48721586574959225,0.799211355497515,0.43063611569630367;
                        0.5412643417564378,0.8360770243233578,0.47223168036068724;
                        0.5944914782774172,0.8695307941152899,0.5168680312297448;
                        0.646966339987892,0.8996642410031294,0.5646387588128834;
                        0.6988842187826441,0.9265224108066539,0.6157768530380421;
                        0.750599643454158,0.9500766313430901,0.6707551434162453;
                        0.802710656871834,0.9701715148223337,0.7304651045038929;
                        0.8562659463009292,0.986404739714624,0.7966069771448052;
                        0.9133549217611615,0.997790981395447,0.8727568829799067;
                        0.9794834758075935,1.0,0.9685901147422417];
                case 'red'
                    map = [0.42613120912574187,0.04414420137670496,0.04656278392186578;
                        0.5397977147005079,0.05511996262092147,0.05710672903962483;
                        0.6478144828457755,0.07135234168781962,0.07088432198925954;
                        0.7494841060391776,0.09407584004926794,0.0886862258318675;
                        0.8435247860002442,0.12508047275944334,0.11214590730658563;
                        0.9262930296450576,0.170016549211707,0.14649141860595666;
                        0.9787978140043189,0.2542325209873713,0.215874654907593;
                        1.0,0.34212851079292883,0.2933518821597799;
                        1.0,0.419351213815478,0.3649024273066629;
                        1.0,0.4884605347144387,0.43158749694061355;
                        1.0,0.5512731631324418,0.49433271115995076;
                        1.0,0.608922709922859,0.5536896544250512;
                        1.0,0.6621598751305112,0.6099934009549784;
                        1.0,0.7115177587940515,0.6634624600780062;
                        1.0,0.7573991051213195,0.7142526248977504;
                        1.0,0.800124144548663,0.762485867864988;
                        1.0,0.8399580728613485,0.8082660592299563;
                        1.0,0.8771275333381613,0.851687579017044;
                        1.0,0.9118308976354084,0.8928399976390634;
                        1.0,0.9442449125805817,0.9318105378756991];
                case 'violet'
                    map = [0.23694263026978052,0.01667791951185743,0.40704749910083193;
                        0.2857617519553948,0.12221346400989264,0.5071724257265463;
                        0.331338437933445,0.20641622161516576,0.5881727598978995;
                        0.3778107114382734,0.2816600917406628,0.6556459691401266;
                        0.42583336894882545,0.35081101068992193,0.7124988599643509;
                        0.47480608128167484,0.41501098256717717,0.760624345305249;
                        0.5238136238532445,0.47489902481429597,0.8014092573292334;
                        0.5720080219523104,0.5309066046293904,0.8359407061255087;
                        0.6187283460103694,0.5833595204049925,0.8651065560695841;
                        0.6635101586808497,0.6325215685368966,0.8896508563636978;
                        0.7060567632609881,0.6786164218658961,0.9102074549316423;
                        0.7462025961941616,0.72183993505547,0.9273220112928126;
                        0.7838797860144886,0.7623676646347692,0.9414673801296757;
                        0.8190908606913807,0.8003597322751567,0.953054973105098;
                        0.8518875860934199,0.8359640876699412,0.9624435469543492;
                        0.8823549941419173,0.8693187488173404,0.9699462597009906;
                        0.9105995223021621,0.9005533628980404,0.9758365040807412;
                        0.9367403105709867,0.9297903052579725,0.9803528374703382;
                        0.9609028860999503,0.957145460970582,0.9837032159737534;
                        0.9832146386371473,0.9827287879459315,0.9860686728760596];
                case 'blue'
                    map = [0.04184267699355549,0.15664452792479422,0.3415970823047533;
                        0.07367216649275424,0.22439260245439785,0.4464446306026834;
                        0.0997416930949723,0.29201548434867913,0.5419861568563157;
                        0.12151125468690352,0.35874643471380635,0.6267369073625101;
                        0.14200637854159073,0.4239009957018894,0.6998492096377024;
                        0.16614731480445724,0.48682866946765674,0.7607600870932886;
                        0.19977673801084134,0.5468783523160806,0.8090483654491641;
                        0.24554345282353934,0.6033496510489177,0.8457767082745965;
                        0.30080521307663977,0.655721867653852,0.8737914723157274;
                        0.3621606245393861,0.7038173332395578,0.8953487733647949;
                        0.42678847546010557,0.7476826330283934,0.9121698907775515;
                        0.4926227249234772,0.7874950897080805,0.9256020758873873;
                        0.5582178366579083,0.8235031356680647,0.9367087849176818;
                        0.622581170982724,0.8559890388790732,0.9463251148741724;
                        0.6850412954311673,0.8852458209472639,0.9550958647875988;
                        0.7451547852997189,0.9115631379158914,0.9635048723706058;
                        0.8026415743585998,0.9352188524741903,0.9718999032331895;
                        0.8573397533521553,0.9564742487798915,0.9805150796199186;
                        0.9091734314863626,0.9755715930379173,0.9894915999080977;
                        0.9581294680051805,0.992733207255479,0.998896852851051];
                case 'gray'
                    map = gray(20);
                case 'warm'
                    map = hot(20);
                case 'plan'
                    map = gray(2);
            end
            app.activeColormap = map;
            colormap(app.UIAxes, map);
            img = imagesc(app.UIAxes, X);
            if wifi
                app.wifiSource = line(app.UIAxes,app.router(2),app.router(1), 'LineWidth',10,'Marker','.','MarkerEdgeColor','w','MarkerSize', 15);
            end
            img.ButtonDownFcn = @(~,e) app.setRouter(e.IntersectionPoint(1:2));
            axis(app.UIAxes, 'equal', [ 0 size(app.planMatrix,2) 0 size(app.planMatrix,1)]);
        end
        
        function setRouter(app, router_pos)
            router_pos = flip(ceil(router_pos));
            if app.planMatrix(router_pos(1), router_pos(2)) == 1
                app.router = router_pos;
                delete(app.crossMark);
                app.crossMark = line(app.UIAxes,app.router(2),app.router(1), 'LineWidth',2,'Marker','x','MarkerEdgeColor','r','MarkerSize', 10);
                app.RouterpositionEditField.Value = sprintf('(%.0f, %.0f)', flip(app.router));
            end
        end
        
        function changeSurface(app, filename, router_pos)
            img = imread(filename);
            app.planMatrix = mat2gray(img);
            app.planMatrix(app.planMatrix > 0) = 1;
            app.activeImage = app.planMatrix+1;
            app.draw(app.activeImage, 'plan', false);
            app.setRouter(router_pos);
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.changeSurface('schemes/plan_1.png', [870, 425]);
        end

        % Button pushed function: DrawWiFiButton
        function DrawWiFiButtonPushed(app, event)
            app.activeImage = app.calculateSignal(app.router);
            app.draw(app.activeImage, app.ColorDropDown.Value, true);
        end

        % Button pushed function: SaveimageButton
        function SaveimageButtonPushed(app, event)
            filename = app.FilenameEditField.Value;
            if isempty(filename)
                filename = 'myWifiSignal';
            end
            imwrite(app.activeImage,app.activeColormap,sprintf('%s.png', filename))
        end

        % Value changed function: SurfaceDropDown
        function SurfaceDropDownValueChanged(app, event)
            value = app.SurfaceDropDown.Value;
            switch value
                case 'plan 1'
                    app.changeSurface('schemes/plan_1.png', [803, 423]);
                case 'plan 2'
                    app.changeSurface('schemes/plan_2.png', [520,290]);
                case 'plan 3'
                    app.changeSurface('schemes/plan_3.png', [600, 370]);
                case 'plan 4'
                    app.changeSurface('schemes/plan_4.png', [388, 325]);
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            app.ColorDropDownLabel = uilabel(app.UIFigure);
            app.ColorDropDownLabel.Text = 'Color';
            app.ColorDropDown = uidropdown(app.UIFigure);
            app.ColorDropDown.Items = {'blue', 'green', 'violet', 'red', 'warm', 'gray'};
            app.ColorDropDown.Value = 'blue';
            app.SaveimageButton = uibutton(app.UIFigure, 'push');
            app.SaveimageButton.ButtonPushedFcn = createCallbackFcn(app, @SaveimageButtonPushed, true);
            app.SaveimageButton.Text = 'Save image';
            app.WiFiVisualiserLabel = uilabel(app.UIFigure);
            app.WiFiVisualiserLabel.Text = 'WiFi Visualiser';
            app.SurfaceDropDownLabel = uilabel(app.UIFigure);
            app.SurfaceDropDownLabel.Text = 'Surface';
            app.SurfaceDropDown = uidropdown(app.UIFigure);
            app.SurfaceDropDown.Items = {'plan 1', 'plan 2', 'plan 3', 'plan 4'};
            app.SurfaceDropDown.ValueChangedFcn = createCallbackFcn(app, @SurfaceDropDownValueChanged, true);
            app.SurfaceDropDown.Value = 'plan 1';
            app.DrawWiFiButton = uibutton(app.UIFigure, 'push');
            app.DrawWiFiButton.ButtonPushedFcn = createCallbackFcn(app, @DrawWiFiButtonPushed, true);
            app.DrawWiFiButton.Text = 'Draw WiFi';
            app.RouterpositionEditFieldLabel = uilabel(app.UIFigure);
            app.RouterpositionEditFieldLabel.Text = 'Router position';
            app.RouterpositionEditField = uieditfield(app.UIFigure, 'text');
            app.RouterpositionEditField.Editable = 'off';
            app.FrequencyButtonGroup = uibuttongroup(app.UIFigure);
            app.FrequencyButtonGroup.Title = 'Frequency';
            app.GHzButton = uiradiobutton(app.FrequencyButtonGroup);
            app.GHzButton.Text = '2.4 GHz';
            app.GHzButton.Value = true;
            app.GHzButton_2 = uiradiobutton(app.FrequencyButtonGroup);
            app.GHzButton_2.Text = '5 GHz';
            app.FilenameEditFieldLabel = uilabel(app.UIFigure);
            app.FilenameEditFieldLabel.Text = 'Filename';
            app.FilenameEditField = uieditfield(app.UIFigure, 'text');
            app.FilenameEditField.Value = 'myWifiSignal';
        end
    end

    methods (Access = public)

        % Construct app
        function app = wifiapp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end